classdef audiosettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DeviceOutputDropDownLabel  matlab.ui.control.Label
        DeviceInputDropDownLabel   matlab.ui.control.Label
        AudioDeviceDropDown        matlab.ui.control.DropDown
        AudioDeviceDropDownLabel   matlab.ui.control.Label
        DeviceInputDropDown        matlab.ui.control.DropDown
        DeviceOutputDropDown       matlab.ui.control.DropDown
        SampleRateDropDown         matlab.ui.control.DropDown
        BufferSizeDropDown         matlab.ui.control.DropDown
        AsioSettingsButton         matlab.ui.control.Button
        LatencyTestButton          matlab.ui.control.Button
        latencyMS                  matlab.ui.control.TextArea
        MonitoringCheckBox         matlab.ui.control.CheckBox
        BufferSizeDropDownLabel    matlab.ui.control.Label
        SampleRateDropDownLabel    matlab.ui.control.Label
    end

    %{
    

    %}
    properties (Access = public)
        audioDevice 
        audioDeviceInput 
        audioDeviceOutput 
        samplerate 
        bufferSize 
        audioStreamer
    end
    
    
    methods (Access = public)
        
        function startupAudio(app)
            % create audio streamer with default settings
            app.audioStreamer = audiostreamer("full-duplex", ...
                    "SampleRate", 48000, ...
                    "DeviceBufferSize", 512);
        end

        
        function closeAudio(app)
            release(audio.Streamer);

        end
    end

    methods (Access = private)
        
        function startupAudioSettings(app)
            % setup device option
            app.AudioDeviceDropDown.Items = audiostreamer.getDrivers;
            
            % device option callback to update IO options
            app.AudioDeviceDropDownValueChanged;

            % samplerate, buffer callback to update ui and properties
            app.SampleRateDropDownValueChanged;
            app.BufferSizeDropDownValueChanged;
        end
        
        
        function [inputs,outputs] = getDeviceIO(app)

            allDevices = audiostreamer.getAudioDevices;

            % make empty arrays for audio inputs and outputs
            inputs = {};
            outputs = {};
            
            % loop over all audio inputs and outputs and add to array if
            % corresponds to active driver
            for i=1:numel(allDevices)
                if allDevices(i).Driver == app.audioDevice 
                    if allDevices(i).MaxRecorderChannels > 0
                        inputs{end+1} = allDevices(i).Name;
                    end
                    
                    if allDevices(i).MaxPlayerChannels > 0
                        outputs{end+1} = allDevices(i).Name;
                    end
                end
            end
            
            % convert array to array type UI likes
            inputs = cell2mat(inputs);
            outputs = cell2mat(outputs);
        end
      

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % temporary audio startup
            startupAudio(app)
            % startAudioStreamer(app)
            startupAudioSettings(app)
        end

        % Value changed function: AudioDeviceDropDown
        function AudioDeviceDropDownValueChanged(app, event)
            
            % pause audio while value is changing
            if app.audioStreamer.isPlaying                 
               app.audioStreamer.pause;             
            end

            app.audioDevice = app.AudioDeviceDropDown.Value;
            app.audioStreamer.Driver = app.audioDevice;

            % enable asio settings button if using asio
            if app.audioDevice == "ASIO"
                app.AsioSettingsButton.Enable
            end

            % change audio device input and output options based on device
            [inputs, outputs] = getDeviceIO(app);
            app.DeviceInputDropDown.Items = inputs;
            app.DeviceOutputDropDown.Items = outputs;

            % resume audio 
            if app.audioStreamer.isRecorderPaused || app.audioStreamer.isPlayerPaused
                app.audioStreamer.resume;
            end 

        end

        % Value changed function: DeviceInputDropDown
        function DeviceInputDropDownValueChanged(app, event)
            
            % pause audio while value is changing
            if app.audioStreamer.isPlaying                 
               app.audioStreamer.pause;             
            end
            
            app.audioDeviceInput = app.DeviceInputDropDown.Value;
            app.audioStreamer.Recorder = app.audioDeviceInput;
            
            % resume audio 
            if app.audioStreamer.isRecorderPaused || app.audioStreamer.isPlayerPaused
                app.audioStreamer.resume;
            end 

        end

        % Value changed function: DeviceOutputDropDown
        function DeviceOutputDropDownValueChanged(app, event)
            
            % pause audio while value is changing
            if app.audioStreamer.isPlaying                 
               app.audioStreamer.pause;             
            end

            app.audioDeviceOutput = app.DeviceOutputDropDown.Value;
            app.audioStreamer.Player = app.audioDeviceOutput;
     
            % resume audio
            if app.audioStreamer.isRecorderPaused || app.audioStreamer.isPlayerPaused
                app.audioStreamer.resume;
            end

        end

        % Value changed function: SampleRateDropDown
        function SampleRateDropDownValueChanged(app, event)
            
            % pause audio while value is changing
            if app.audioStreamer.isPlaying
                app.audioStreamer.pause;
            end

            app.samplerate = str2double(app.SampleRateDropDown.Value);
            app.audioStreamer.SampleRate = app.samplerate;
            
            % resume audio
            if app.audioStreamer.isRecorderPaused || app.audioStreamer.isPlayerPaused
                app.audioStreamer.resume;
            end

        end

        % Value changed function: BufferSizeDropDown
        function BufferSizeDropDownValueChanged(app, event)
            % pause audio while value is changing
            if app.audioStreamer.isPlaying                 
                app.audioStreamer.pause;             
            end
            
            app.bufferSize = str2double(app.BufferSizeDropDown.Value);
            app.audioStreamer.DeviceBufferSize = app.bufferSize;

            % resume audio
            if app.audioStreamer.isRecorderPaused || app.audioStreamer.isPlayerPaused
                app.audioStreamer.resume;
            end

        end

        % Button pushed function: LatencyTestButton
        function LatencyTestButtonPushed(app, event)
            app.audioStreamer.RecorderChannels = 1;
            app.audioStreamer.PlayerChannels = 1;

            latency = measureLoopbackLatency(app.audioStreamer);
            latencyMs = round(1000*latency/app.samplerate, 1);
            latencyText = strcat(string(latencyMs)," ms (",string(latency)," samples)");
            app.latencyMS.Value = latencyText;
        end

        % Button pushed function: AsioSettingsButton
        function AsioSettingsButtonPushed(app, event)
            if app.audioDevice == "ASIO"
                asiosettings
            end
        end

        % Value changed function: MonitoringCheckBox
        function MonitoringCheckBoxValueChanged(app, event)
            record(app.audioStreamer, 512);
            pause(1)
            
            % kens monitoring code temporarily in the settings app to test
            while app.MonitoringCheckBox.Value == true
                if app.audioStreamer.NumRecorderSamples > 0
                    stream = read(app.audioStreamer,app.audioStreamer.NumRecorderSamples);
                    playrec(app.audioStreamer,stream);
                end
            end
            if app.MonitoringCheckBox.Value == false
                stop(app.audioStreamer)
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SampleRateDropDownLabel = uilabel(app.UIFigure);
            app.SampleRateDropDownLabel.Text = 'Sample Rate';
            app.BufferSizeDropDownLabel = uilabel(app.UIFigure);
            app.BufferSizeDropDownLabel.Text = 'Buffer Size';
            app.MonitoringCheckBox = uicheckbox(app.UIFigure);
            app.MonitoringCheckBox.ValueChangedFcn = createCallbackFcn(app, @MonitoringCheckBoxValueChanged, true);
            app.MonitoringCheckBox.Text = 'Monitoring';
            app.latencyMS = uitextarea(app.UIFigure);
            app.latencyMS.Editable = 'off';
            app.LatencyTestButton = uibutton(app.UIFigure, 'push');
            app.LatencyTestButton.ButtonPushedFcn = createCallbackFcn(app, @LatencyTestButtonPushed, true);
            app.LatencyTestButton.Text = 'Latency Test';
            app.AsioSettingsButton = uibutton(app.UIFigure, 'push');
            app.AsioSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @AsioSettingsButtonPushed, true);
            app.AsioSettingsButton.Enable = 'off';
            app.AsioSettingsButton.Text = 'ASIO Settings';
            app.BufferSizeDropDown = uidropdown(app.UIFigure);
            app.BufferSizeDropDown.Items = {'64', '128', '256', '512', '1024'};
            app.BufferSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @BufferSizeDropDownValueChanged, true);
            app.BufferSizeDropDown.Value = '512';
            app.SampleRateDropDown = uidropdown(app.UIFigure);
            app.SampleRateDropDown.Items = {'44.1 kHz', '48.0 kHz'};
            app.SampleRateDropDown.ItemsData = {'44100', '48000'};
            app.SampleRateDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleRateDropDownValueChanged, true);
            app.SampleRateDropDown.Value = '48000';
            app.DeviceOutputDropDown = uidropdown(app.UIFigure);
            app.DeviceOutputDropDown.Items = {};
            app.DeviceOutputDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceOutputDropDownValueChanged, true);
            app.DeviceOutputDropDown.Placeholder = 'Select';
            app.DeviceOutputDropDown.Value = {};
            app.DeviceInputDropDown = uidropdown(app.UIFigure);
            app.DeviceInputDropDown.Items = {};
            app.DeviceInputDropDown.ValueChangedFcn = createCallbackFcn(app, @DeviceInputDropDownValueChanged, true);
            app.DeviceInputDropDown.Placeholder = 'Select';
            app.DeviceInputDropDown.Value = {};
            app.AudioDeviceDropDownLabel = uilabel(app.UIFigure);
            app.AudioDeviceDropDownLabel.Text = 'Audio Device';
            app.AudioDeviceDropDown = uidropdown(app.UIFigure);
            app.AudioDeviceDropDown.Items = {};
            app.AudioDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioDeviceDropDownValueChanged, true);
            app.AudioDeviceDropDown.Placeholder = 'Select';
            app.AudioDeviceDropDown.Value = {};
            app.DeviceInputDropDownLabel = uilabel(app.UIFigure);
            app.DeviceInputDropDownLabel.Text = 'Device Input';
            app.DeviceOutputDropDownLabel = uilabel(app.UIFigure);
            app.DeviceOutputDropDownLabel.Text = 'Device Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audiosettings

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end