classdef audioHostApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AudioSwitch         matlab.ui.control.ToggleSwitch
        OpenSettingsButton  matlab.ui.control.Button
        MonitoringCheckBox  matlab.ui.control.CheckBox
        AudioSwitchLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        audioDevice 
        audioDeviceInput 
        audioDeviceOutput 
        samplerate 
        bufferSize 
        audioStreamer
        audioSettings
        previousSettings
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.audioDevice = "";
            app.audioDeviceInput = "";
            app.audioDeviceOutput = "";
        end

        % Value changed function: AudioSwitch
        function AudioSwitchValueChanged(app, event)
            if event.ValueIndex == 2
                % create audiostreamer with default settings
                app.audioStreamer = audiostreamer("Mode","full-duplex","DeviceBufferSize",512);
                
                % set properties
                app.samplerate = app.audioStreamer.SampleRate;
                app.bufferSize = app.audioStreamer.DeviceBufferSize;
                
                % enable settings and monitoring options
                app.OpenSettingsButton.Enable = "on";
                app.MonitoringCheckBox.Enable = "on";
            end
            
            if event.ValueIndex == 1
                % save properties for easy recall
                app.previousSettings = app;

                % release audiostreamer
                release(app.audioStreamer);
                app.OpenSettingsButton.Enable = "off";
                app.MonitoringCheckBox.Enable = "off";
            end
        end

        % Button pushed function: OpenSettingsButton
        function OpenSettingsButtonPushed(app, event)
           % open audosettings app
           audiosettings(app);
        end

        % Value changed function: MonitoringCheckBox
        function MonitoringCheckBoxValueChanged(app, event)
            record(app.audioStreamer, 512);
            pause(1)
            
            % quick copy of kens monitoring code 
            while app.MonitoringCheckBox.Value == true
                if app.audioStreamer.NumRecorderSamples > 0
                    stream = read(app.audioStreamer,app.audioStreamer.NumRecorderSamples);
                    playrec(app.audioStreamer,stream);
                end
            end
            if app.MonitoringCheckBox.Value == false
                stop(app.audioStreamer)
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AudioSwitchLabel = uilabel(app.UIFigure);
            app.AudioSwitchLabel.Text = 'Audio';
            app.MonitoringCheckBox = uicheckbox(app.UIFigure);
            app.MonitoringCheckBox.ValueChangedFcn = createCallbackFcn(app, @MonitoringCheckBoxValueChanged, true);
            app.MonitoringCheckBox.Enable = 'off';
            app.MonitoringCheckBox.Text = 'Monitoring';
            app.OpenSettingsButton = uibutton(app.UIFigure, 'push');
            app.OpenSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSettingsButtonPushed, true);
            app.OpenSettingsButton.Enable = 'off';
            app.OpenSettingsButton.Text = 'Open Settings';
            app.AudioSwitch = uiswitch(app.UIFigure, 'toggle');
            app.AudioSwitch.ValueChangedFcn = createCallbackFcn(app, @AudioSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audioHostApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end