classdef MidtermVirtualListener < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        ImportAudioFileMenu           matlab.ui.container.Menu
        AudioSettings                 matlab.ui.container.Menu
        VirtualListenerTextArea       matlab.ui.control.TextArea
        VirtualListenerTextAreaLabel  matlab.ui.control.Label
        AudioParametersPanel          matlab.ui.container.Panel
        ReverbWetDryMixSlider_2Label  matlab.ui.control.Label
        VolumeSlider_2Label           matlab.ui.control.Label
        PitchSlider_2Label            matlab.ui.control.Label
        ReverbWetDryMixSlider         matlab.ui.control.Slider
        VolumeSlider                  matlab.ui.control.Slider
        PitchSlider                   matlab.ui.control.Slider
        SamplesdroppedframeEditField  matlab.ui.control.NumericEditField
        SamplesdroppedframeEditFieldLabel  matlab.ui.control.Label
        SystemStatusLamp              matlab.ui.control.Lamp
        SystemStatusLabel             matlab.ui.control.Label
        AudioPlaybackSwitch           matlab.ui.control.RockerSwitch
        AudioPlaybackSwitchLabel      matlab.ui.control.Label
        AudioMeter                    audio.ui.control.Meter
        MUST5510AudioTestBenchLabel   matlab.ui.control.Label
    end

%{

Revised File:

MUST 5510 Audio Test Bench Week 3
Copyright 2025 (c) Aston K. McCullough, PhD, MS, MA
Northeastern University


Original file:
MUST 5973 Audio Testbench Week 3


Description:
Application for playing an audio track in real-time, and augmenting audio
parameters using a GUI-based slider. The application also implements
MIRToolbox for analyzing audio parameters and modifications in real-time.

%}

    properties (Access = private)
        fileReader % Audio file reader

        deviceWriter = audioDeviceWriter( ...
            'SampleRate',44100, ...
            'SupportVariableSizeInput',true,'BufferSize',1024*3); % max buffer size 65536

        % audio augmentation object
        augmenter = audioDataAugmenter( ...
            "AugmentationMode","sequential", ...
            "AugmentationParameterSource",'specify',...
            "NumAugmentations",1, ...
            ...
            "ApplyTimeStretch",false, ...
            "SpeedupFactor", 0, ...
            ...
            "ApplyPitchShift",false, ...
            "SemitoneShift",0, ...
            ...
            "ApplyVolumeControl",false, ...
            "VolumeGain",0, ...
            ...
            "ApplyAddNoise",false, ...
            "SNR",0,...
            ...
            "ApplyTimeShift",false, ...
            "TimeShift", 0);

        scopeVisualizeTime = timescope('TimeSpan',2, ...
            'YLimits',[-1,1], ...
            'TimeSpanOverrunAction',"Scroll",...
            "LayoutDimensions",[1 1]);

        peakMeter = audioLevelMeter() % Audio level meter object
        
        fs % temporoary sampling rate

        reverbObject = reverberator % reverberator object

        % user-defined class using MIRToolbox
        mirWiz = mirtoolboxWizard(zeros(44100,2))

        mirWizTimer

        audioSettings = 
    end

    methods (Access = private)

        function [] = streamAudio(app)
            % stream audio from file

            playbackSwitchStatus = app.AudioPlaybackSwitch.Value;

            while ~isDone(app.fileReader) && ...
                    strcmp(playbackSwitchStatus,"Play")
                               
                drawnow
                playbackSwitchStatus = app.AudioPlaybackSwitch.Value;
                
                signal = app.fileReader();

                % augment audio
                aug_signal = augmentAudio(app,signal);
                
                % update virtual listener with new audio data
                step(app.mirWiz,aug_signal);

                dropped = app.deviceWriter(aug_signal);
                app.SamplesdroppedframeEditField.UserData = double(dropped);
                app.AudioMeter.Value = app.peakMeter(aug_signal); 

                if double(dropped) ~= 0
                    app.SamplesdroppedframeEditField.Value = double(dropped);
                    drawnow
                end

            end

            release(app.fileReader)
            release(app.deviceWriter)
            release(app.scopeVisualizeTime)

            % stop timer
            stop(app.mirWizTimer);
            set(app.AudioPlaybackSwitch,"Value",'Stop')

        end

        function transformed_signal = augmentAudio(app,orig_signal)
            % apply augmentation to streaming audio signal
      
            % Apply augmentation
            transformed_signal = augment(app.augmenter,orig_signal,app.fs);

            if istable(transformed_signal)
                transformed_signal = cell2mat(transformed_signal.Audio);
            end

        end

        function audioOut = reverbApply(app,audio,preDelay,wetDryMix,sampleRate)
            % custom augmentation method: Reverb

            app.reverbObject.SampleRate = sampleRate;
            app.reverbObject.PreDelay = preDelay;
            app.reverbObject.WetDryMix = wetDryMix;

            audioOut = app.reverbObject(audio);
        end


        function mirWizTimerFcn(app,~,~)

            query(app.mirWiz)

            % send request for information from
            % virtual listener every 6 s
            virtualListenerUpdatePeriod = 6; % seconds

            if numel(app.mirWiz.pulseClarity) >= virtualListenerUpdatePeriod

                try
                    % print to GUI
                    formatSpec = ['Pulse clarity = %g \n' ...
                        'Tempo = %g bpm\n' ...
                        'Brightness = %g \n' ];

                    A1 = app.mirWiz.pulseClarity(end);

                    A2 = app.mirWiz.tempo(end);

                    A3 = app.mirWiz.brightness(end);

                    str = sprintf(formatSpec,round(A1,2), ...
                        round(A2), ...
                        round(A3,1),round(A4,1),round(A5,1));

                    app.VirtualListenerTextArea.Value = str;
                 
                catch


                end

            else

                app.VirtualListenerTextArea.Value = ...
                    ['Listening. Please wait. . . ',char(datetime("now"))];

            end

            if isempty(app.SamplesdroppedframeEditField.UserData)
                app.SamplesdroppedframeEditField.UserData = 0;
            end

            app.SamplesdroppedframeEditField.Value = ...
                        app.SamplesdroppedframeEditField.UserData;

            drawnow

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            % initialize paralell pool, if not running 
            gcp;

            % reset audio devices
            audiodevreset

            % set slider UI component ranges

                % pitch
                    app.PitchSlider.Limits = [-2 2];
                    app.PitchSlider.Value = 0;
    
                % volume
                    app.VolumeSlider.Limits = [-20 10];
                    app.VolumeSlider.Value = 0;                
                    
                % reverb: wet/dry mix
                    app.ReverbWetDryMixSlider.Limits = [0 1];
                    app.ReverbWetDryMixSlider.Value = 0; 


            % add MIRToolbox to Audio App
            d = dir('**/*');
            d = d(matches({d.name},'MIRToolbox'));

            addpath(genpath(fullfile(d.folder,d.name)))

            % setup MIRToolbox objects
            % assign to empty properties

            app.mirWizTimer = timer("ExecutionMode","fixedRate","Period",6, ...
                "BusyMode","drop","TimerFcn",@app.mirWizTimerFcn);

            % set audio augmenter paramters
            % define augmenter parameters for ShiftPitch
            setAugmenterParams(app.augmenter,'shiftPitch','LockPhase',true, ...
                'PreserveFormants',true);

            % define augmenter parameters for StretchAudio
            setAugmenterParams(app.augmenter,'stretchAudio','LockPhase',true);

            % update system status lamp color
            app.SystemStatusLamp.Color = 'y';

        end

        % Menu selected function: ImportAudioFileMenu
        function ImportAudioFileMenuSelected(app, event)


            try

                % User input: select audio file
                [filename,pathname] = uigetfile('*.*','Select Audio File');
                [~,app.fs] = audioread(fullfile(pathname,filename));
          
                % set frame length to 300 ms
                frameLength = 44100*0.3;
                
                % initialize audio file reader
                app.fileReader = dsp.AudioFileReader( ...
                    fullfile(pathname,filename), ...
                    'SamplesPerFrame',frameLength);

                % Add reverb as parameter to audio data augmenter
                if sum(contains(cellstr(properties(app.augmenter)), ...
                        'ApplyReverb'))==1

                    removeAugmentationMethod(app.augmenter,'Reverb')

                end

                % create reverberator system object
                algorithmName = 'Reverb';
                algorithmHandle = @(x,preDelay,wetDryMix)reverbApply(app,x,preDelay,wetDryMix,app.fs);
                parameters = {'PreDelay','WetDryMix'};
                parameterRanges = {[0,1],[0,1]};
                parameterValues = {0,0};

                addAugmentationMethod(app.augmenter,algorithmName,algorithmHandle, ...
                    'AugmentationParameter',parameters, ...
                    'ParameterRange',parameterRanges, ...
                    'ParameterValue',parameterValues)
                app.augmenter.ApplyReverb = false;

                % update timescope parameters
                app.scopeVisualizeTime.SampleRate = app.fileReader.SampleRate;
                app.scopeVisualizeTime.BufferLength = ...
                    app.fileReader.SampleRate*2*2;

                % update sample rate
                app.deviceWriter.SampleRate = app.fs;


                % update audio level parameters
                app.peakMeter.SampleRate = app.fileReader.SampleRate;
                app.peakMeter.WindowLength = app.fileReader.SamplesPerFrame;

                % update status lamp
                app.SystemStatusLamp.Color = 'g';

                % update access to playback switch
                app.AudioPlaybackSwitch.Enable = true;

            catch
             
                % update status lamp
                app.SystemStatusLamp.Color = 'y';

                % update access to playback switch
                app.AudioPlaybackSwitch.Enable = false;

            end


        end

        % Value changed function: AudioPlaybackSwitch
        function AudioPlaybackSwitchValueChanged(app, event)
            value = app.AudioPlaybackSwitch.Value;

            switch value

                case 'Play'

                    if strcmp(app.mirWizTimer.Running,"off")
                        start(app.mirWizTimer);
                    end
                    streamAudio(app)

                case 'Stop'

                    stop(app.mirWizTimer);

            end
        end

        % Value changed function: PitchSlider
        function PitchSliderValueChanged(app, event)
            value = app.PitchSlider.Value;

            % check CONTROLLER 1
            if round(value,1) == 0 % if control value is close to 0
                % remove augmentation

                app.augmenter.ApplyPitchShift = false;
            else

                app.augmenter.ApplyPitchShift = true;
                app.augmenter.SemitoneShift =  value;

                drawnow
            end
            
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            value = app.VolumeSlider.Value;

            % check CONTROLLER 2
            if round(value) == 0 % if control value is close to 0
                % remove augmentation

                app.augmenter.ApplyVolumeControl = false;
            else

                app.augmenter.ApplyVolumeControl = true;
                app.augmenter.VolumeGain =  value;

                drawnow
              
            end
        end

        % Value changed function: ReverbWetDryMixSlider
        function ReverbWetDryMixSliderValueChanged(app, event)
            value = app.ReverbWetDryMixSlider.Value;
            
            % check CONTROLLER 3
            if round(value,1) == 0 % if control value is close to 0
                % remove augmentation

                app.augmenter.ApplyReverb = false;
            else

                app.augmenter.ApplyReverb = true;
                app.augmenter.WetDryMix =  value;
                app.augmenter.PreDelay = rand(1);

                drawnow

            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            if ~isa(app.fileReader,'double')
                release(app.fileReader)
            end

            if ~isa(app.deviceWriter,'double')
                release(app.deviceWriter)
            end

            if ~isa(app.scopeVisualizeTime,'double')
                release(app.scopeVisualizeTime)
            end

            
            stop(app.mirWizTimer);
            delete(app.mirWizTimer);

            delete(app)

        end

        % Menu selected function: AudioSettings
        function AudioSettingsMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ImportAudioFileMenu = uimenu(app.Menu);
            app.ImportAudioFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportAudioFileMenuSelected, true);
            app.ImportAudioFileMenu.Text = 'Import Audio File';
            app.AudioSettings = uimenu(app.Menu);
            app.AudioSettings.MenuSelectedFcn = createCallbackFcn(app, @AudioSettingsMenuSelected, true);
            app.AudioSettings.Text = 'Audio Settings';
            app.MUST5510AudioTestBenchLabel = uilabel(app.UIFigure);
            app.MUST5510AudioTestBenchLabel.Text = 'MUST 5510 Audio Test Bench';
            app.AudioMeter = uiaudiometer(app.UIFigure);
            app.AudioPlaybackSwitchLabel = uilabel(app.UIFigure);
            app.AudioPlaybackSwitchLabel.Enable = 'off';
            app.AudioPlaybackSwitchLabel.Text = 'Audio Playback';
            app.AudioPlaybackSwitch = uiswitch(app.UIFigure, 'rocker');
            app.AudioPlaybackSwitch.Items = {'Play', 'Stop'};
            app.AudioPlaybackSwitch.ValueChangedFcn = createCallbackFcn(app, @AudioPlaybackSwitchValueChanged, true);
            app.AudioPlaybackSwitch.Enable = 'off';
            app.AudioPlaybackSwitch.Value = 'Stop';
            app.SystemStatusLabel = uilabel(app.UIFigure);
            app.SystemStatusLabel.Text = 'System Status';
            app.SystemStatusLamp = uilamp(app.UIFigure);
            app.SamplesdroppedframeEditFieldLabel = uilabel(app.UIFigure);
            app.SamplesdroppedframeEditFieldLabel.Text = 'Samples dropped/frame';
            app.SamplesdroppedframeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AudioParametersPanel = uipanel(app.UIFigure);
            app.AudioParametersPanel.Title = 'Audio Parameters';
            app.PitchSlider = uislider(app.AudioParametersPanel);
            app.PitchSlider.ValueChangedFcn = createCallbackFcn(app, @PitchSliderValueChanged, true);
            app.VolumeSlider = uislider(app.AudioParametersPanel);
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.ReverbWetDryMixSlider = uislider(app.AudioParametersPanel);
            app.ReverbWetDryMixSlider.ValueChangedFcn = createCallbackFcn(app, @ReverbWetDryMixSliderValueChanged, true);
            app.PitchSlider_2Label = uilabel(app.AudioParametersPanel);
            app.PitchSlider_2Label.Text = 'Pitch';
            app.VolumeSlider_2Label = uilabel(app.AudioParametersPanel);
            app.VolumeSlider_2Label.Text = 'Volume';
            app.ReverbWetDryMixSlider_2Label = uilabel(app.AudioParametersPanel);
            app.ReverbWetDryMixSlider_2Label.Text = 'Reverb: Wet/Dry Mix';
            app.VirtualListenerTextAreaLabel = uilabel(app.UIFigure);
            app.VirtualListenerTextAreaLabel.Text = 'Virtual Listener';
            app.VirtualListenerTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MidtermVirtualListener

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end