classdef MUST5510_AudioTestBench_Week10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        ImportAudioFileMenu           matlab.ui.container.Menu
        BackendAppStatusLamp          matlab.ui.control.Lamp
        BackendAppStatusLampLabel     matlab.ui.control.Label
        VirtualListenerTextArea       matlab.ui.control.TextArea
        VirtualListenerTextAreaLabel  matlab.ui.control.Label
        SamplesdroppedframeEditField  matlab.ui.control.NumericEditField
        SamplesdroppedframeEditFieldLabel  matlab.ui.control.Label
        SystemStatusLamp              matlab.ui.control.Lamp
        SystemStatusLabel             matlab.ui.control.Label
        AudioPlaybackSwitch           matlab.ui.control.RockerSwitch
        AudioPlaybackSwitchLabel      matlab.ui.control.Label
        AudioMeter                    audio.ui.control.Meter
        MUST5510AudioTestBenchLabel   matlab.ui.control.Label
    end


    properties (Access = private)

        fileReader % Audio file reader

        deviceWriter = audioDeviceWriter( ...
            'SampleRate',44100, ... %
            'SupportVariableSizeInput',true,'BufferSize',65536); % max buffer size 65536

        % audio augmentation object
        augmenter = audioDataAugmenter( ...
            "AugmentationMode","sequential", ...
            "AugmentationParameterSource",'specify',...
            "NumAugmentations",1, ...
            ...
            "ApplyTimeStretch",false, ...
            "SpeedupFactor", 0, ...
            ...
            "ApplyPitchShift",false, ...
            "SemitoneShift",0, ...
            ...
            "ApplyVolumeControl",false, ...
            "VolumeGain",0, ...
            ...
            "ApplyAddNoise",false, ...
            "SNR",0,...
            ...
            "ApplyTimeShift",false, ...
            "TimeShift", 0);

        scopeVisualizeTime = timescope('TimeSpan',2, ...
            'YLimits',[-1,1], ...
            'TimeSpanOverrunAction',"Scroll",...
            "LayoutDimensions",[1 1]);

        peakMeter = audioLevelMeter() % Audio level meter object

        fs % temporoary sampling rate

        reverbObject = reverberator % reverberator object

        % user-defined class using MIRToolbox
        mirWiz = mirtoolboxWizard(zeros(44100,2))

        % mirWiz timer object
        mirWizTimer

        midiMsgBank  % midi message array

        wvSynth = wavetableSynthesizer('SampleRate',44.1e3,'Amplitude',8) 

        startTime % App start time

        midiMap % a mapping of midi notes to keyboard values

        soundShareObj % create object to connect to Backend application

        musicAugmenter % NV music augmenter object
       
    end


    methods (Access = private)

        function [] = streamAudio(app)

            % set all audio variables as persistent variables
            persistent signal blendAudio midiOut augmenterAudio

            % stream audio from file

            playbackSwitchStatus = app.AudioPlaybackSwitch.Value;

            while ~isDone(app.fileReader) && ...
                    strcmp(playbackSwitchStatus,"Play")

                drawnow
                playbackSwitchStatus = app.AudioPlaybackSwitch.Value;

                signal = app.fileReader();

                % augment audio
                % aug_signal = augmentAudio(app,signal);

                % get backend augmented audio and/or midi
                try
                    midiOut = app.soundShareObj.Value;
                catch
                    midiOut = 0;
                end

                blendAudio = signal + midiOut;

                % update virtual listener with new audio data
                step(app.mirWiz,blendAudio);

                dropped = app.deviceWriter(blendAudio);
                app.SamplesdroppedframeEditField.UserData = double(dropped);
                app.AudioMeter.Value = app.peakMeter(blendAudio);

                if double(dropped) ~= 0
                    app.SamplesdroppedframeEditField.Value = double(dropped);
                    drawnow
                end

            end

            release(app.fileReader)
            release(app.deviceWriter)
            release(app.scopeVisualizeTime)

            % stop timer
            stop(app.mirWizTimer);
            set(app.AudioPlaybackSwitch,"Value",'Stop')

        end

        function transformed_signal = augmentAudio(app,orig_signal)
            % apply augmentation to streaming audio signal

            % Apply augmentation
            transformed_signal = augment(app.augmenter,orig_signal,app.fs);

            if istable(transformed_signal)
                transformed_signal = cell2mat(transformed_signal.Audio);
            end

        end

        function audioOut = reverbApply(app,audio,preDelay,wetDryMix,sampleRate)
            
            % custom augmentation method: Reverb
            app.reverbObject.SampleRate = sampleRate;
            app.reverbObject.PreDelay = preDelay;
            app.reverbObject.WetDryMix = wetDryMix;

            audioOut = app.reverbObject(audio);

        end


        function mirWizTimerFcn(app,~,~)

            query(app.mirWiz)

            % send request for information from
            % virtual listener every 6 s
            virtualListenerUpdatePeriod = 6; % seconds

            if numel(app.mirWiz.pulseClarity) >= virtualListenerUpdatePeriod

                try
                    % TODO: change MIRWiz to have the parameters we want

                    % print to GUI
                    formatSpec = ['Pulse clarity = %g \n' ...
                        'Tempo = %g bpm\n' ...
                        'Key = %s\n' ];

                    A1 = app.mirWiz.pulseClarity(end);

                    A2 = app.mirWiz.tempo(end);

                    A3 = app.mirWiz.key.note{end};
                   
                    str = sprintf(formatSpec,round(A1,2), ...
                        round(A2), ...
                        A3);

                    app.VirtualListenerTextArea.Value = str;

                catch

                end

            else

                app.VirtualListenerTextArea.Value = ...
                    ['Listening. Please wait. . . ',char(datetime("now"))];

            end

            app.SamplesdroppedframeEditField.Value = ...
                app.SamplesdroppedframeEditField.UserData;

            drawnow

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            % initialize paralell pool, if not running
            gcp;

            % reset audio devices
            audiodevreset

            % set slider UI component ranges

            % % pitch
            % app.PitchSlider.Limits = [-2 2];
            % app.PitchSlider.Value = 0;
            % 
            % % volume
            % app.VolumeSlider.Limits = [-20 10];
            % app.VolumeSlider.Value = 0;
            % 
            % % reverb: wet/dry mix
            % app.ReverbWetDryMixSlider.Limits = [0 1];
            % app.ReverbWetDryMixSlider.Value = 0;


            % add MIRToolbox to Audio App
            d = dir('**/*');
            d = d(matches({d.name},'MIRToolbox'));
            d_ma = d(matches({d.name}, 'MusicAugmenter.m'));

            % add path for MusicAugmenter at runtime
            addpath(genpath(fullfile(d.folder,d.name)))
            addpath(genpath(fullfile(d_ma.folder,d_ma.name)))

            % setup MIRToolbox objects
            % assign to empty properties

            app.mirWizTimer = timer("ExecutionMode","fixedRate","Period",6, ...
                "BusyMode","drop","TimerFcn",@app.mirWizTimerFcn);

            app.SamplesdroppedframeEditField.UserData = 0; % set default value

            % set audio augmenter paramters
            % define augmenter parameters for ShiftPitch
            setAugmenterParams(app.augmenter,'shiftPitch','LockPhase',true, ...
                'PreserveFormants',true);

            % define augmenter parameters for StretchAudio
            setAugmenterParams(app.augmenter,'stretchAudio','LockPhase',true);

            % log app start time
            app.startTime = datetime("now");

            % setup midiMap
            app.midiMap = [[113 50 119 51 101 114 53 116 54 121 55 117 105]' ...
                (60:72)'];

           % check that Backend instance is running
           if exist('SoundApp_BakendDAT.mat','file')
               % instance is running
               app.BackendAppStatusLamp.Color = 'g';
               app.soundShareObj = matfile('SoundApp_BakendDAT.mat');
               
           else
               % instance is not running
               app.BackendAppStatusLamp.Color = 'r';
               app.soundShareObj = 0;
           end

        end

        % Menu selected function: ImportAudioFileMenu
        function ImportAudioFileMenuSelected(app, event)


            try

                % User input: select audio file
                [filename,pathname] = uigetfile('*.*','Select Audio File');
                [audio_in,app.fs] = audioread(fullfile(pathname,filename));

                % set frame length equal to 2x synthesizer
                frameLength = app.wvSynth.SamplesPerFrame * 10;
                app.wvSynth.SamplesPerFrame = frameLength;

                % initialize audio file reader
                app.fileReader = dsp.AudioFileReader( ...
                    fullfile(pathname,filename), ...
                    'SamplesPerFrame',frameLength);

                % Add reverb as parameter to audio data augmenter
                if sum(contains(cellstr(properties(app.augmenter)), ...
                        'ApplyReverb'))==1

                    removeAugmentationMethod(app.augmenter,'Reverb')

                end

                % create reverberator system object
                algorithmName = 'Reverb';
                algorithmHandle = @(x,preDelay,wetDryMix)reverbApply(app,x,preDelay,wetDryMix,app.fs);
                parameters = {'PreDelay','WetDryMix'};
                parameterRanges = {[0,1],[0,1]};
                parameterValues = {0,0};

                addAugmentationMethod(app.augmenter,algorithmName,algorithmHandle, ...
                    'AugmentationParameter',parameters, ...
                    'ParameterRange',parameterRanges, ...
                    'ParameterValue',parameterValues)
                app.augmenter.ApplyReverb = false;

                % update timescope parameters
                app.scopeVisualizeTime.SampleRate = app.fileReader.SampleRate;
                app.scopeVisualizeTime.BufferLength = ...
                    app.fileReader.SampleRate*2*2;

                % update sample rate
                app.deviceWriter.SampleRate = app.fs;
                app.deviceWriter.BufferSize = 1024;


                % instantiate MusicAugmenter 
                augmenter_buffer_ns = 10;
                app.musicAugmenter = MusicAugmenter(audio_in(1:app.fs*augmenter_buffer_ns),...
                    app.fs, augmenter_buffer_ns, app.deviceWriter.BufferSize);


                % update audio level parameters
                app.peakMeter.SampleRate = app.fileReader.SampleRate;
                app.peakMeter.WindowLength = app.fileReader.SamplesPerFrame;

                % update status lamp
                app.SystemStatusLamp.Color = 'g';

                % update access to playback switch
                app.AudioPlaybackSwitch.Enable = true;

                % update audio oscillator samples per frame
                app.wvSynth.SamplesPerFrame = frameLength;

            catch ME

                % update status lamp
                app.SystemStatusLamp.Color = 'y';

                % update access to playback switch
                app.AudioPlaybackSwitch.Enable = false;

            end


        end

        % Value changed function: AudioPlaybackSwitch
        function AudioPlaybackSwitchValueChanged(app, event)
            value = app.AudioPlaybackSwitch.Value;

            switch value

                case 'Play'

                    if strcmp(app.mirWizTimer.Running,"off")
                        start(app.mirWizTimer);
                    end
                    streamAudio(app)

                case 'Stop'

                    stop(app.mirWizTimer);

            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           
            if ~isempty(app.fileReader)
                release(app.fileReader)
            end

            release(app.deviceWriter)
            release(app.scopeVisualizeTime)

            stop(app.mirWizTimer);
            delete(app.mirWizTimer);

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ImportAudioFileMenu = uimenu(app.Menu);
            app.ImportAudioFileMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportAudioFileMenuSelected, true);
            app.ImportAudioFileMenu.Text = 'Import Audio File';
            app.MUST5510AudioTestBenchLabel = uilabel(app.UIFigure);
            app.MUST5510AudioTestBenchLabel.Text = 'MUST 5510 Audio Test Bench';
            app.AudioMeter = uiaudiometer(app.UIFigure);
            app.AudioPlaybackSwitchLabel = uilabel(app.UIFigure);
            app.AudioPlaybackSwitchLabel.Enable = 'off';
            app.AudioPlaybackSwitchLabel.Text = 'Audio Playback';
            app.AudioPlaybackSwitch = uiswitch(app.UIFigure, 'rocker');
            app.AudioPlaybackSwitch.Items = {'Play', 'Stop'};
            app.AudioPlaybackSwitch.ValueChangedFcn = createCallbackFcn(app, @AudioPlaybackSwitchValueChanged, true);
            app.AudioPlaybackSwitch.Enable = 'off';
            app.AudioPlaybackSwitch.Value = 'Stop';
            app.SystemStatusLabel = uilabel(app.UIFigure);
            app.SystemStatusLabel.Text = 'System Status';
            app.SystemStatusLamp = uilamp(app.UIFigure);
            app.SamplesdroppedframeEditFieldLabel = uilabel(app.UIFigure);
            app.SamplesdroppedframeEditFieldLabel.Text = 'Samples dropped/frame';
            app.SamplesdroppedframeEditField = uieditfield(app.UIFigure, 'numeric');
            app.VirtualListenerTextAreaLabel = uilabel(app.UIFigure);
            app.VirtualListenerTextAreaLabel.Text = 'Virtual Listener';
            app.VirtualListenerTextArea = uitextarea(app.UIFigure);
            app.BackendAppStatusLampLabel = uilabel(app.UIFigure);
            app.BackendAppStatusLampLabel.Text = 'Backend App Status';
            app.BackendAppStatusLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MUST5510_AudioTestBench_Week10

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end